classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Button            matlab.ui.control.Button
        Label_7           matlab.ui.control.Label
        Surname           matlab.ui.control.EditField
        EditField_2Label  matlab.ui.control.Label
        Name              matlab.ui.control.EditField
        EditField_3Label  matlab.ui.control.Label
        Patronymic        matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        S_Rus             matlab.ui.control.NumericEditField
        S_Math            matlab.ui.control.NumericEditField
        S_ICT             matlab.ui.control.NumericEditField
        Label_8           matlab.ui.control.Label
        Border            matlab.ui.control.NumericEditField
        Label_9           matlab.ui.control.Label
        SUM               matlab.ui.control.NumericEditField
        Output            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: S_Rus
        function S_RusValueChanged(app, event)
            app.SUM.Value = app.S_Rus.Value + app.S_Math.Value + app.S_ICT.Value;
        end

        % Value changed function: S_Math
        function S_MathValueChanged(app, event)
            app.SUM.Value = app.S_Rus.Value + app.S_Math.Value + app.S_ICT.Value;
        end

        % Value changed function: S_ICT
        function S_ICTValueChanged(app, event)
            app.SUM.Value = app.S_Rus.Value + app.S_Math.Value + app.S_ICT.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.SUM.Value>=app.Border.Value)
                app.Output.Text="Поступил";
                drawnow
        else
            app.Output.Text="Не поступил";
            drawnow
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычислить';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Фамилия';
            app.Surname = uieditfield(app.UIFigure, 'text');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Имя';
            app.Name = uieditfield(app.UIFigure, 'text');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'Отчество';
            app.Patronymic = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ФИО';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предмет';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Баллы ЕГЭ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Русский язык';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Математика';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Информатика';
            app.S_Rus = uieditfield(app.UIFigure, 'numeric');
            app.S_Rus.ValueChangedFcn = createCallbackFcn(app, @S_RusValueChanged, true);
            app.S_Math = uieditfield(app.UIFigure, 'numeric');
            app.S_Math.ValueChangedFcn = createCallbackFcn(app, @S_MathValueChanged, true);
            app.S_ICT = uieditfield(app.UIFigure, 'numeric');
            app.S_ICT.ValueChangedFcn = createCallbackFcn(app, @S_ICTValueChanged, true);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'Проходной балл:';
            app.Border = uieditfield(app.UIFigure, 'numeric');
            app.Border.Value = 270;
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Сумма баллов';
            app.SUM = uieditfield(app.UIFigure, 'numeric');
            app.Output = uilabel(app.UIFigure);
            app.Output.Text = 'Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end