classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        Build1Button  matlab.ui.control.Button
        Build2Button  matlab.ui.control.Button
        Clear1Button  matlab.ui.control.Button
        Clear2Button  matlab.ui.control.Button
        RightPanel    matlab.ui.container.Panel
        UIAxes_1      matlab.ui.control.UIAxes
        UIAxes_2      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Build1Button
        function Build1ButtonPushed(app, event)
            x=-2:0.2:2;
            funq=exp(-x.^2);
            plot(app.UIAxes_1,x,funq),
            title(app.UIAxes_1,'e^(-x^2)');
            xlabel(app.UIAxes_1,'X = -2:0.2:2')

        end

        % Button pushed function: Clear1Button
        function Clear1ButtonPushed(app, event)
            cla(app.UIAxes_1);
            title(app.UIAxes_1,'');
            xlabel(app.UIAxes_1,'X');
        end

        % Button pushed function: Clear2Button
        function Clear2ButtonPushed(app, event)
            cla(app.UIAxes_2);
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'X');
        end

        % Button pushed function: Build2Button
        function Build2ButtonPushed(app, event)
            x=0:0.1:4*pi;
            funq=sin(x);
            plot(app.UIAxes_2,x,funq),
            title(app.UIAxes_2,'sin(x)');
            xlabel(app.UIAxes_2,'X = 0:0.1:4*pi');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {142, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Build1Button = uibutton(app.LeftPanel, 'push');
            app.Build1Button.ButtonPushedFcn = createCallbackFcn(app, @Build1ButtonPushed, true);
            app.Build1Button.Text = 'Build 1';
            app.Build2Button = uibutton(app.LeftPanel, 'push');
            app.Build2Button.ButtonPushedFcn = createCallbackFcn(app, @Build2ButtonPushed, true);
            app.Build2Button.Text = 'Build 2';
            app.Clear1Button = uibutton(app.LeftPanel, 'push');
            app.Clear1Button.ButtonPushedFcn = createCallbackFcn(app, @Clear1ButtonPushed, true);
            app.Clear1Button.Text = 'Clear 1';
            app.Clear2Button = uibutton(app.LeftPanel, 'push');
            app.Clear2Button.ButtonPushedFcn = createCallbackFcn(app, @Clear2ButtonPushed, true);
            app.Clear2Button.Text = 'Clear 2';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_1 = uiaxes(app.RightPanel);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end