classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        fxEditFieldLabel  matlab.ui.control.Label
        fx                matlab.ui.control.EditField
        x0EditFieldLabel  matlab.ui.control.Label
        x0                matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        sumN              matlab.ui.control.NumericEditField
        ButtonF           matlab.ui.control.Button
        ButtonT           matlab.ui.control.Button
        Label_2           matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        RightPanel        matlab.ui.container.Panel
        PlotF             matlab.ui.control.UIAxes
        PlotT             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function Pol= Pol2Txt(app,P)
            syms z Pol
n=length(P);
Pol=0;
for i=1:n, Pol=Pol+P(n+1-i)*z^(i-1); 
end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonF
        function ButtonFPushed(app, event)
            fplot(app.PlotF,app.fx.Value)
        end

        % Button pushed function: ButtonT
        function ButtonTPushed(app, event)
            %строим разложение в ряд Т
            pause on;
            sym x
            f= (app.fx.Value);
            fc=str2sym(f);
            start=app.x0.Value;
            try
            for k= 1 : app.sumN.Value
            t= taylor(fc,'ExpansionPoint', start,'Order',k);
            fplot(app.PlotT,t,[-5,5])
            pause(0.5);
            end
            t=simplify(t,'IgnoreAnalyticConstraints', true,'Steps',10)
            t=matlabFunction(t);
            app.TextArea.Value=func2str(t);
            catch
                 app.TextArea.Value="Can't calcualate T. series"; 
            end
            %%Вывод ряда
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.fxEditFieldLabel = uilabel(app.LeftPanel);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fx = uieditfield(app.LeftPanel, 'text');
            app.fx.Value = 'exp^x.*sin(-x)';
            app.x0EditFieldLabel = uilabel(app.LeftPanel);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0 = uieditfield(app.LeftPanel, 'numeric');
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'Кол-во '; 'слагаемых'};
            app.sumN = uieditfield(app.LeftPanel, 'numeric');
            app.sumN.Value = 1;
            app.ButtonF = uibutton(app.LeftPanel, 'push');
            app.ButtonF.ButtonPushedFcn = createCallbackFcn(app, @ButtonFPushed, true);
            app.ButtonF.Text = 'Plot f(x)';
            app.ButtonT = uibutton(app.LeftPanel, 'push');
            app.ButtonT.ButtonPushedFcn = createCallbackFcn(app, @ButtonTPushed, true);
            app.ButtonT.Text = 'Ряд Тейлора';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = {'Ряд '; 'Тейлора'};
            app.TextArea = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.PlotF = uiaxes(app.RightPanel);
            title(app.PlotF, 'Graph')
            xlabel(app.PlotF, 'X')
            ylabel(app.PlotF, 'Y')
            app.PlotT = uiaxes(app.RightPanel);
            title(app.PlotT, 'Разложение в ряд Тейлора')
            xlabel(app.PlotT, 'X')
            ylabel(app.PlotT, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end